classdef Nuclear_thermal_rocket_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Percentage_error                matlab.ui.control.NumericEditField
        ErrorLabel                      matlab.ui.control.Label
        PumpoutletpressureLabel_2       matlab.ui.control.Label
        PumpoutletpressureLabel         matlab.ui.control.Label
        OptimiziationusingLabel         matlab.ui.control.Label
        Switch_Mass_flow_rate_optimization  matlab.ui.control.Switch
        Switch_pump_outlet_optimization  matlab.ui.control.Switch
        GraphsLabel                     matlab.ui.control.Label
        Graphs_tab                      matlab.ui.container.TabGroup
        Graphs_tab_1                    matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Graphs_Tab_2                    matlab.ui.container.Tab
        UIAxes_pressure_vs_entropy      matlab.ui.control.UIAxes
        UIAxes_temp_vs_entropy          matlab.ui.control.UIAxes
        DefinedConstantTabGroup         matlab.ui.container.TabGroup
        TankTab                         matlab.ui.container.Tab
        TankstoragepressurekPaP1EditField  matlab.ui.control.NumericEditField
        TankstoragepressurekPaP1EditFieldLabel  matlab.ui.control.Label
        TankstoragetemperatureKT1EditField  matlab.ui.control.NumericEditField
        TankstoragetemperatureKT1EditFieldLabel  matlab.ui.control.Label
        Speceficheatatconstantpressure  matlab.ui.control.NumericEditField
        SpeceficheatatconstantvolumeCvLabel_2  matlab.ui.control.Label
        Speceficheatatconstantvolume    matlab.ui.control.NumericEditField
        SpeceficheatatconstantvolumeCvLabel  matlab.ui.control.Label
        Tab2_3                          matlab.ui.container.Tab
        Gravity_affecting_rocket        matlab.ui.control.NumericEditField
        SpeceficheatatconstantvolumeCvLabel_3  matlab.ui.control.Label
        InputTabGroup                   matlab.ui.container.TabGroup
        SpaceandtimeTab                 matlab.ui.container.Tab
        Nozzle_exit_area                matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Nozzle_throat_area              matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        MaximumaccelerationEditField    matlab.ui.control.NumericEditField
        MaximumaccelerationastraunautscanwithstandEditFieldLabel  matlab.ui.control.Label
        OverallmassofrocketEditField    matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        InitialvelocitykmhrEditField    matlab.ui.control.NumericEditField
        InitialvelocityofthesecondstageNTProcketkmhrEditFieldLabel  matlab.ui.control.Label
        TimetocoverhrsEditField         matlab.ui.control.NumericEditField
        TimetocoverthisdistancehrsEditFieldLabel  matlab.ui.control.Label
        DistancetocoverkmEditField      matlab.ui.control.NumericEditField
        DistancetocoverkmEditFieldLabel  matlab.ui.control.Label
        TurbopumpTab                    matlab.ui.container.Tab
        PaLabel                         matlab.ui.control.Label
        design_duty_pressure            matlab.ui.control.NumericEditField
        kgsLabel                        matlab.ui.control.Label
        design_duty_rpmEditField        matlab.ui.control.NumericEditField
        MaxrpmEditFieldLabel            matlab.ui.control.Label
        DesigndutyEditField             matlab.ui.control.NumericEditField
        DesigndutyEditFieldLabel        matlab.ui.control.Label
        NozzleHeatexchangerTab          matlab.ui.container.Tab
        LengthofpipeEditField_4         matlab.ui.control.NumericEditField
        LengthofpipeEditField_4Label    matlab.ui.control.Label
        LengthofpipeEditField_3         matlab.ui.control.NumericEditField
        LengthofpipeEditField_3Label    matlab.ui.control.Label
        DiameterofpipeEditField         matlab.ui.control.NumericEditField
        DiameterofpipeEditFieldLabel    matlab.ui.control.Label
        LengthofpipeEditField           matlab.ui.control.NumericEditField
        LengthofpipeEditFieldLabel      matlab.ui.control.Label
        NuclearreactorheatexchangerTab  matlab.ui.container.Tab
        OutputTabGroup                  matlab.ui.container.TabGroup
        PrimaryTab                      matlab.ui.container.Tab
        thrust_calculated               matlab.ui.control.NumericEditField
        ThrustforcerequiredLabel_7      matlab.ui.control.Label
        velocity_ejected_gas            matlab.ui.control.NumericEditField
        ThrustforcerequiredLabel_6      matlab.ui.control.Label
        subsonic_mach_number            matlab.ui.control.NumericEditField
        ThrustforcerequiredLabel_5      matlab.ui.control.Label
        supersonic_mach_number          matlab.ui.control.NumericEditField
        ThrustforcerequiredLabel_4      matlab.ui.control.Label
        Actual_mass_flow_rate           matlab.ui.control.NumericEditField
        ThrustforcerequiredLabel_3      matlab.ui.control.Label
        specific_impulse                matlab.ui.control.NumericEditField
        ThrustforcerequiredLabel_2      matlab.ui.control.Label
        ThrustforceEditField            matlab.ui.control.NumericEditField
        ThrustforcerequiredLabel        matlab.ui.control.Label
        Total_accelaration_timeEditField  matlab.ui.control.NumericEditField
        RequiredaccelarationtimeminsEditFieldLabel  matlab.ui.control.Label
        FinalspeedkmhrEditField         matlab.ui.control.NumericEditField
        FinalspeedrequiredkmhrEditFieldLabel  matlab.ui.control.Label
        SecondaryTab                    matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        StateofsystemTab                matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        StateDiagramTab                 matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        No_of_stepsEditField            matlab.ui.control.NumericEditField
        NoofstepsEditFieldLabel         matlab.ui.control.Label
        TypicalinputvaluesbasedonreallifedataTextArea  matlab.ui.control.TextArea
        DefinedconstantsLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SimulateButton                  matlab.ui.control.Button
        OutputsLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        NuclearthermalrocketsimulatorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Assigning inputed and constant values to respective varialbe

            C_P = app.Speceficheatatconstantpressure.Value; %specific heat at constant pressure of 900000 Pa
            C_V = app.Speceficheatatconstantvolume.Value; %specific heat at constant volume
            Specific_heat_ratio = C_P/C_V; % Specific heat ratio
            R_u = 8.31445; % universal gas constant
            G = app.Gravity_affecting_rocket.Value; % constant, but can be dynamically updated according rocket distance from earth and mars
            Steps_number = app.No_of_stepsEditField.Value;
            Vector_Velocity = zeros(Steps_number); % Defining vector to store different values of velocity
            Vector_Thrust = zeros(Steps_number); % Defining vector to store different values of thrust
            Vector_Specific_impulse = zeros(Steps_number); % Defining vector to store different values of specific impulse
            Vector_mass_flow_rate = zeros(Steps_number); % Defining vector to store different values of mass flow rate
            calculated_thrust_force = 0; % initial declaration
            Distance = app.DistancetocoverkmEditField.Value; % km
            time = app.TimetocoverhrsEditField.Value; % seconds
            Initialvelocity = app.InitialvelocitykmhrEditField.Value; % m/s
            Maximum_acceleration = app.MaximumaccelerationEditField.Value; % m/s^2
            mass = app.OverallmassofrocketEditField.Value; % kg
            exit_area = app.Nozzle_exit_area.Value; % m/s^2
            throat_area = app.Nozzle_throat_area.Value; % m/s^2
            State_Table_matrix = zeros(6,5);
            design_duty_rpm = app.design_duty_rpmEditField.Value; % rpm
            Design_duty = app.DesigndutyEditField.Value; % kg/s
            Design_duty_operating_pressure = app.design_duty_pressure.Value;
            Pump_constant = Design_duty/design_duty_rpm; % kg/(s*rpm)
            error = app.Percentage_error.Value;
            Ratio_throatarea_exitarea = exit_area/throat_area;
            [Msub, Msup] = area_mach_relation_test(Ratio_throatarea_exitarea,Specific_heat_ratio);
           

            % Using newton laws of motion to determine the required final
            % speed, acceleration and thrust for the rocket.

            Finalspeed = (Distance)/(time);
            acceleration_time = (((Finalspeed*(5/18)) - (Initialvelocity*(5/18)))/(Maximum_acceleration))/60;
            Thrust = mass*Maximum_acceleration;

            % Defining state 1
            P1 = app.TankstoragepressurekPaP1EditField.Value;
            T1 = app.TankstoragetemperatureKT1EditField.Value;
            H1 = py.CoolProp.CoolProp.PropsSI('H', 'T', T1, 'P', P1, 'hydrogen');
            S1 = py.CoolProp.CoolProp.PropsSI('S', 'T', T1, 'P', P1, 'hydrogen');

            if strcmp(app.Switch_pump_outlet_optimization.Value, 'On')

            % Optimizing using outlet pressure of the pump, assuming pump is isentropic and guessing pump outlet pressure

                P2 = Design_duty_operating_pressure; % Initial guess value in kPa
                S2 = S1;
                mass_flow_rate = Design_duty; % kg/s

                for i = 1:Steps_number
                    holding_old_design_duty_rpm = design_duty_rpm;
                    
                    if (abs(calculated_thrust_force - Thrust)/Thrust) <= (error/100)
                        break;

                    elseif calculated_thrust_force < Thrust
                        % increase the pump rpm, thus increasing the mass flow rate
                        design_duty_rpm = design_duty_rpm + 1000;
                        design_duty_rpm_ratio = design_duty_rpm/holding_old_design_duty_rpm;
                        mass_flow_rate = Pump_constant * design_duty_rpm;
                        P2 = P2*(design_duty_rpm_ratio^2);
                        fprintf("%f",P2);

                    elseif calculated_thrust_force > Thrust
                        % decrease the pump rmp, thus decreasing the mass
                        % flow rate
                        design_duty_rpm = design_duty_rpm - 1000;
                        design_duty_rpm_ratio = design_duty_rpm/holding_old_design_duty_rpm;
                        mass_flow_rate = Pump_constant * design_duty_rpm;
                        P2 = P2*(design_duty_rpm_ratio^2);
                        fprintf("%f",P2);
                    end

                    % Defining state 2
                    H2 = py.CoolProp.CoolProp.PropsSI('H', 'S', S2, 'P', P2, 'hydrogen');
                    T2 = py.CoolProp.CoolProp.PropsSI('T', 'S', S2, 'P', P2, 'hydrogen');

                    % Making the assumption that no pressure drop occurs in the hydrogen, as it circulates through the
                    % hot reactor structure, where the hydrogen is gasified and is increased to a temperature of 100K.
                    % Making initial assumption on Nozzle exit
                    % and reactor structure temperature T6

                    T6 = 900; % Kelvin, we know this temperature as this should be lower than the melting point of the metal structure.
                    U_A_1 = 1500; % Watt/((m^2)*K), we can know this as well if we guess the enthalpy of fluid
                    C_P_1 = 100; % (J/kg*K) Updating C_P value for temperature of 150 K

                    % Defining state 3
                    T3 = (T6 -((T6-T2)*(exp(-U_A_1/(mass_flow_rate*C_P_1) ))));
                    P3 = P2;
                    H3 = py.CoolProp.CoolProp.PropsSI('H', 'T', T3, 'P', P3, 'hydrogen');
                    S3 = py.CoolProp.CoolProp.PropsSI('S', 'T', T3, 'P', P3, 'hydrogen');

                    % Recalling that the turbine is isentropic and using below Eq. to determine the turbine exit enthalpy
                    % H4 = H3 - H2 + H1 and S4 = S3

                    % Defining state 4
                    H4 = H3 - H2 + H1;
                    S4 = S3;
                    T4 = py.CoolProp.CoolProp.PropsSI('T', 'H', H4, 'S', S4, 'hydrogen');
                    P4 = py.CoolProp.CoolProp.PropsSI('P', 'H', H4, 'S', S4, 'hydrogen');

                    % Since we know the temperature at which reactor operates we
                    % can defined it as constant.

                    Reactor_temperature = 3000; % Kelvin

                    % Hydrogen from the turbine exit is passed through the reactor
                    % where it exhanges heat with reactor and its temperature is
                    % increased to T5

                    U_A_2 = 200000;
                    C_P_2 = 14566;

                    % Defining state 5
                    T5 = (Reactor_temperature -((Reactor_temperature-T4)*(exp(-U_A_2/(mass_flow_rate*C_P_2) ))));
                    P5 = P4-(0.3*P4);
                    H5 = py.CoolProp.CoolProp.PropsSI('H', 'T', T5, 'P', P5, 'hydrogen');
                    S5 = py.CoolProp.CoolProp.PropsSI('S', 'T', T5, 'P', P5, 'hydrogen');

                   
                    a1 = Specific_heat_ratio - 1;
                    b1 = 1+(a1/2)*(Msup^2);

                    % Defining state 6
                    T6 = T5*(b1^-1);
                    P6 = P5*(b1^(-Specific_heat_ratio/a1));
                    H6 = py.CoolProp.CoolProp.PropsSI('H', 'T', T6, 'P', P6, 'hydrogen');
                    S6 = py.CoolProp.CoolProp.PropsSI('S', 'T', T6, 'P', P6, 'hydrogen');

                    velocity_of_ejected_gas = Msup*(sqrt(Specific_heat_ratio*R_u*1000*T6)); % in meters/seconds

                    calculated_thrust_force = velocity_of_ejected_gas * mass_flow_rate + P6*app.Nozzle_exit_area.Value; % in Newtons

                    specific_impulse_calculated = velocity_of_ejected_gas/G;

                    % Storing the value of ejected velocity for plotting
                    Vector_Velocity(i) = velocity_of_ejected_gas;
                    Vector_Thrust(i) = calculated_thrust_force;
                    Vector_Specific_impulse(i) = specific_impulse_calculated;
                    Vector_mass_flow_rate(i) = mass_flow_rate;
                    

                end

                % Passing values to the output field values

                app.Total_accelaration_timeEditField.Value = acceleration_time;
                app.subsonic_mach_number.Value = Msub;
                app.supersonic_mach_number.Value = Msup;
                app.thrust_calculated.Value = calculated_thrust_force;
                app.Actual_mass_flow_rate.Value = mass_flow_rate;
                app.velocity_ejected_gas.Value = velocity_of_ejected_gas;
                app.FinalspeedkmhrEditField.Value = Finalspeed;
                app.ThrustforceEditField.Value = Thrust;
                app.specific_impulse.Value = specific_impulse_calculated;

 
                % Passing values to matrix to assign it to the table

                State_Table_matrix(1,1) = 1;
                State_Table_matrix(1,2) = T1;
                State_Table_matrix(1,3) = P1;
                State_Table_matrix(1,4) = S1;
                State_Table_matrix(1,5) = H1;

                State_Table_matrix(2,1) = 2;
                State_Table_matrix(2,2) = T2;
                State_Table_matrix(2,3) = P2;
                State_Table_matrix(2,4) = S2;
                State_Table_matrix(2,5) = H2;

                State_Table_matrix(3,1) = 3;
                State_Table_matrix(3,2) = T3;
                State_Table_matrix(3,3) = P3;
                State_Table_matrix(3,4) = S3;
                State_Table_matrix(3,5) = H3;

                State_Table_matrix(4,1) = 4;
                State_Table_matrix(4,2) = T4;
                State_Table_matrix(4,3) = P4;
                State_Table_matrix(4,4) = S4;
                State_Table_matrix(4,5) = H4;

                State_Table_matrix(5,1) = 5;
                State_Table_matrix(5,2) = T5;
                State_Table_matrix(5,3) = P5;
                State_Table_matrix(5,4) = S5;
                State_Table_matrix(5,5) = H5;

                State_Table_matrix(6,1) = 6;
                State_Table_matrix(6,2) = T6;
                State_Table_matrix(6,3) = P6;
                State_Table_matrix(6,4) = S6;
                State_Table_matrix(6,5) = H6;

                Vector_state_temp = [T1, T2, T3, T4, T5, T6];
                Vector_state_pressure = [P1, P2, P3, P4, P5, P6];
                Vector_state_entropy = [S1, S2, S3, S4, S5, S6];

                app.UITable.Data = State_Table_matrix;

                % Plotting graphs

                plot(app.UIAxes,Vector_Velocity, Vector_Thrust);
                plot(app.UIAxes_2, Vector_mass_flow_rate, Vector_Specific_impulse);
                plot(app.UIAxes_temp_vs_entropy,Vector_state_entropy, Vector_state_temp);
                plot(app.UIAxes_pressure_vs_entropy, Vector_state_entropy, Vector_state_pressure);


            elseif strcmp(app.Switch_Mass_flow_rate_optimization.Value, 'On')
                fprintf("Mass_flow_rate switch is on");

            elseif strcmp(app.Switch_pump_outlet_optimization.Value, 'Off') && strcmp(app.Switch_Mass_flow_rate_optimization.Value,'Off')
                fprintf("Both switches are off");
            end
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NuclearthermalrocketsimulatorLabel = uilabel(app.UIFigure);
            app.NuclearthermalrocketsimulatorLabel.Text = 'Nuclear thermal rocket simulator';
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Free-Rocket-Ship-clipart.png');
            app.DefinedconstantsLabel = uilabel(app.UIFigure);
            app.DefinedconstantsLabel.Text = 'Defined constants';
            app.TypicalinputvaluesbasedonreallifedataTextArea = uitextarea(app.UIFigure);
            app.TypicalinputvaluesbasedonreallifedataTextArea.Value = {'Distance to mars - 125 million kilometers.'; ''; 'Targeted future time frame to cover this distance is about 3 months or 2160 hours.'; ''; 'Mass of NTP rockets ranges from about 2.5 to 100 tonne.'; ''; 'Maximum acceleration that can be sustained for long period of time is about 5 G''s'; ''; 'The typical values of nuclear rocket ''s nozzle exhaust velocity ranges between 1.7 km/s to 3.9 km/s.'; ''; 'It is expected from the nuclear thermal rocket to have specific impulse of about 700 to 900 seconds'};
            app.NoofstepsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofstepsEditFieldLabel.Text = 'No. of steps';
            app.No_of_stepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.No_of_stepsEditField.Value = 50;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StateofsystemTab = uitab(app.TabGroup);
            app.StateofsystemTab.Title = 'State of system';
            app.UITable = uitable(app.StateofsystemTab);
            app.UITable.ColumnName = {'State point'; 'T (K)'; 'P (Pa)'; 'S (J/kg *K)'; 'H (J/kg)'};
            app.UITable.RowName = {};
            app.StateDiagramTab = uitab(app.TabGroup);
            app.StateDiagramTab.Title = 'State Diagram';
            app.Image2 = uiimage(app.StateDiagramTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'STATE_POINTS_UPDATED_IMAGE.png');
            app.OutputTabGroup = uitabgroup(app.UIFigure);
            app.PrimaryTab = uitab(app.OutputTabGroup);
            app.PrimaryTab.Title = 'Primary';
            app.FinalspeedrequiredkmhrEditFieldLabel = uilabel(app.PrimaryTab);
            app.FinalspeedrequiredkmhrEditFieldLabel.Text = 'Final speed required (km/hr)';
            app.FinalspeedkmhrEditField = uieditfield(app.PrimaryTab, 'numeric');
            app.FinalspeedkmhrEditField.ValueDisplayFormat = '%0.2f';
            app.RequiredaccelarationtimeminsEditFieldLabel = uilabel(app.PrimaryTab);
            app.RequiredaccelarationtimeminsEditFieldLabel.Text = 'Required accelaration time (mins)';
            app.Total_accelaration_timeEditField = uieditfield(app.PrimaryTab, 'numeric');
            app.ThrustforcerequiredLabel = uilabel(app.PrimaryTab);
            app.ThrustforcerequiredLabel.Text = 'Thrust force required (N)';
            app.ThrustforceEditField = uieditfield(app.PrimaryTab, 'numeric');
            app.ThrustforceEditField.ValueDisplayFormat = '%0.2f';
            app.ThrustforcerequiredLabel_2 = uilabel(app.PrimaryTab);
            app.ThrustforcerequiredLabel_2.Text = 'Specific impulse (s)';
            app.specific_impulse = uieditfield(app.PrimaryTab, 'numeric');
            app.specific_impulse.ValueDisplayFormat = '%0.2f';
            app.ThrustforcerequiredLabel_3 = uilabel(app.PrimaryTab);
            app.ThrustforcerequiredLabel_3.Text = 'Required mass flow rate (kg/s)';
            app.Actual_mass_flow_rate = uieditfield(app.PrimaryTab, 'numeric');
            app.Actual_mass_flow_rate.ValueDisplayFormat = '%0.2f';
            app.ThrustforcerequiredLabel_4 = uilabel(app.PrimaryTab);
            app.ThrustforcerequiredLabel_4.Text = 'supersonic mach number';
            app.supersonic_mach_number = uieditfield(app.PrimaryTab, 'numeric');
            app.supersonic_mach_number.ValueDisplayFormat = '%0.3f';
            app.ThrustforcerequiredLabel_5 = uilabel(app.PrimaryTab);
            app.ThrustforcerequiredLabel_5.Text = 'subsonic mach number';
            app.subsonic_mach_number = uieditfield(app.PrimaryTab, 'numeric');
            app.subsonic_mach_number.ValueDisplayFormat = '%0.3f';
            app.ThrustforcerequiredLabel_6 = uilabel(app.PrimaryTab);
            app.ThrustforcerequiredLabel_6.Text = 'Velocity of the ejected gas from nozzle (m/s)';
            app.velocity_ejected_gas = uieditfield(app.PrimaryTab, 'numeric');
            app.velocity_ejected_gas.ValueDisplayFormat = '%0.3f';
            app.ThrustforcerequiredLabel_7 = uilabel(app.PrimaryTab);
            app.ThrustforcerequiredLabel_7.Text = 'Thrust calculated';
            app.thrust_calculated = uieditfield(app.PrimaryTab, 'numeric');
            app.thrust_calculated.ValueDisplayFormat = '%0.3f';
            app.SecondaryTab = uitab(app.OutputTabGroup);
            app.SecondaryTab.Title = 'Secondary';
            app.InputTabGroup = uitabgroup(app.UIFigure);
            app.SpaceandtimeTab = uitab(app.InputTabGroup);
            app.SpaceandtimeTab.Title = 'Space and time';
            app.DistancetocoverkmEditFieldLabel = uilabel(app.SpaceandtimeTab);
            app.DistancetocoverkmEditFieldLabel.Text = 'Distance to cover (km)';
            app.DistancetocoverkmEditField = uieditfield(app.SpaceandtimeTab, 'numeric');
            app.DistancetocoverkmEditField.ValueDisplayFormat = '%0.2f';
            app.DistancetocoverkmEditField.Value = 125000000;
            app.TimetocoverthisdistancehrsEditFieldLabel = uilabel(app.SpaceandtimeTab);
            app.TimetocoverthisdistancehrsEditFieldLabel.Text = 'Time to cover this distance (hrs)';
            app.TimetocoverhrsEditField = uieditfield(app.SpaceandtimeTab, 'numeric');
            app.TimetocoverhrsEditField.Value = 2160;
            app.InitialvelocityofthesecondstageNTProcketkmhrEditFieldLabel = uilabel(app.SpaceandtimeTab);
            app.InitialvelocityofthesecondstageNTProcketkmhrEditFieldLabel.Text = 'Initial velocity of the second stage NTP rocket (km/hr)';
            app.InitialvelocitykmhrEditField = uieditfield(app.SpaceandtimeTab, 'numeric');
            app.InitialvelocitykmhrEditField.ValueDisplayFormat = '%0.2f';
            app.InitialvelocitykmhrEditField.Tooltip = {'dfsdfsdff'};
            app.InitialvelocitykmhrEditField.Value = 40000;
            app.Label = uilabel(app.SpaceandtimeTab);
            app.Label.Text = 'Overall mass of rocket at given time (kg)';
            app.OverallmassofrocketEditField = uieditfield(app.SpaceandtimeTab, 'numeric');
            app.OverallmassofrocketEditField.ValueDisplayFormat = '%0.2f';
            app.OverallmassofrocketEditField.Value = 100000;
            app.MaximumaccelerationastraunautscanwithstandEditFieldLabel = uilabel(app.SpaceandtimeTab);
            app.MaximumaccelerationastraunautscanwithstandEditFieldLabel.Text = 'Maximum acceleration astraunauts can withstand (m/s^2)';
            app.MaximumaccelerationEditField = uieditfield(app.SpaceandtimeTab, 'numeric');
            app.MaximumaccelerationEditField.ValueDisplayFormat = '%0.2f';
            app.MaximumaccelerationEditField.Value = 3;
            app.Label_2 = uilabel(app.SpaceandtimeTab);
            app.Label_2.Text = 'Nozzle throat area (m^2)';
            app.Nozzle_throat_area = uieditfield(app.SpaceandtimeTab, 'numeric');
            app.Nozzle_throat_area.ValueDisplayFormat = '%0.2f';
            app.Nozzle_throat_area.Value = 1;
            app.Label_3 = uilabel(app.SpaceandtimeTab);
            app.Label_3.Text = 'Nozzle exit area (m^2)';
            app.Nozzle_exit_area = uieditfield(app.SpaceandtimeTab, 'numeric');
            app.Nozzle_exit_area.ValueDisplayFormat = '%0.2f';
            app.Nozzle_exit_area.Value = 2;
            app.TurbopumpTab = uitab(app.InputTabGroup);
            app.TurbopumpTab.Title = 'Turbopump';
            app.DesigndutyEditFieldLabel = uilabel(app.TurbopumpTab);
            app.DesigndutyEditFieldLabel.Text = 'Design duty';
            app.DesigndutyEditField = uieditfield(app.TurbopumpTab, 'numeric');
            app.DesigndutyEditField.Value = 80;
            app.MaxrpmEditFieldLabel = uilabel(app.TurbopumpTab);
            app.MaxrpmEditFieldLabel.Text = 'Max rpm';
            app.design_duty_rpmEditField = uieditfield(app.TurbopumpTab, 'numeric');
            app.design_duty_rpmEditField.ValueDisplayFormat = '%d';
            app.design_duty_rpmEditField.Value = 25000;
            app.kgsLabel = uilabel(app.TurbopumpTab);
            app.kgsLabel.Text = 'kg/s @';
            app.design_duty_pressure = uieditfield(app.TurbopumpTab, 'numeric');
            app.design_duty_pressure.ValueDisplayFormat = '%f';
            app.design_duty_pressure.Value = 700000;
            app.PaLabel = uilabel(app.TurbopumpTab);
            app.PaLabel.Text = 'Pa';
            app.NozzleHeatexchangerTab = uitab(app.InputTabGroup);
            app.NozzleHeatexchangerTab.Title = 'Nozzle Heat exchanger';
            app.LengthofpipeEditFieldLabel = uilabel(app.NozzleHeatexchangerTab);
            app.LengthofpipeEditFieldLabel.Text = 'Length of pipe';
            app.LengthofpipeEditField = uieditfield(app.NozzleHeatexchangerTab, 'numeric');
            app.LengthofpipeEditField.ValueDisplayFormat = '%0.2f';
            app.LengthofpipeEditField.Value = 125000000;
            app.DiameterofpipeEditFieldLabel = uilabel(app.NozzleHeatexchangerTab);
            app.DiameterofpipeEditFieldLabel.Text = 'Diameter of pipe';
            app.DiameterofpipeEditField = uieditfield(app.NozzleHeatexchangerTab, 'numeric');
            app.DiameterofpipeEditField.ValueDisplayFormat = '%0.2f';
            app.DiameterofpipeEditField.Value = 125000000;
            app.LengthofpipeEditField_3Label = uilabel(app.NozzleHeatexchangerTab);
            app.LengthofpipeEditField_3Label.Text = 'Length of pipe';
            app.LengthofpipeEditField_3 = uieditfield(app.NozzleHeatexchangerTab, 'numeric');
            app.LengthofpipeEditField_3.ValueDisplayFormat = '%0.2f';
            app.LengthofpipeEditField_3.Value = 125000000;
            app.LengthofpipeEditField_4Label = uilabel(app.NozzleHeatexchangerTab);
            app.LengthofpipeEditField_4Label.Text = 'Length of pipe';
            app.LengthofpipeEditField_4 = uieditfield(app.NozzleHeatexchangerTab, 'numeric');
            app.LengthofpipeEditField_4.ValueDisplayFormat = '%0.2f';
            app.LengthofpipeEditField_4.Value = 125000000;
            app.NuclearreactorheatexchangerTab = uitab(app.InputTabGroup);
            app.NuclearreactorheatexchangerTab.Title = 'Nuclear reactor heat exchanger';
            app.DefinedConstantTabGroup = uitabgroup(app.UIFigure);
            app.TankTab = uitab(app.DefinedConstantTabGroup);
            app.TankTab.Title = 'Tank';
            app.SpeceficheatatconstantvolumeCvLabel = uilabel(app.TankTab);
            app.SpeceficheatatconstantvolumeCvLabel.Text = 'Specefic heat at constant volume (Cv)(J/kg-k)';
            app.Speceficheatatconstantvolume = uieditfield(app.TankTab, 'numeric');
            app.Speceficheatatconstantvolume.Value = 2.43;
            app.SpeceficheatatconstantvolumeCvLabel_2 = uilabel(app.TankTab);
            app.SpeceficheatatconstantvolumeCvLabel_2.Text = 'Specefic heat at constant pressure (Cp) (J/kg-k)';
            app.Speceficheatatconstantpressure = uieditfield(app.TankTab, 'numeric');
            app.Speceficheatatconstantpressure.Value = 3.42;
            app.TankstoragetemperatureKT1EditFieldLabel = uilabel(app.TankTab);
            app.TankstoragetemperatureKT1EditFieldLabel.Text = 'Tank storage temperature (K) - (T1)';
            app.TankstoragetemperatureKT1EditField = uieditfield(app.TankTab, 'numeric');
            app.TankstoragetemperatureKT1EditField.Value = 20;
            app.TankstoragepressurekPaP1EditFieldLabel = uilabel(app.TankTab);
            app.TankstoragepressurekPaP1EditFieldLabel.Text = 'Tank storage pressure (kPa) - (P1)';
            app.TankstoragepressurekPaP1EditField = uieditfield(app.TankTab, 'numeric');
            app.TankstoragepressurekPaP1EditField.ValueDisplayFormat = '%0.2f';
            app.TankstoragepressurekPaP1EditField.Value = 30000;
            app.Tab2_3 = uitab(app.DefinedConstantTabGroup);
            app.Tab2_3.Title = 'Tab2';
            app.SpeceficheatatconstantvolumeCvLabel_3 = uilabel(app.Tab2_3);
            app.SpeceficheatatconstantvolumeCvLabel_3.Text = 'Gravity force affecting the rocket in space (m/s^2)';
            app.Gravity_affecting_rocket = uieditfield(app.Tab2_3, 'numeric');
            app.Gravity_affecting_rocket.Value = 9.8;
            app.Graphs_tab = uitabgroup(app.UIFigure);
            app.Graphs_tab_1 = uitab(app.Graphs_tab);
            app.Graphs_tab_1.Title = 'Primary';
            app.UIAxes = uiaxes(app.Graphs_tab_1);
            title(app.UIAxes, 'Velocity of exhaust gas vs Thrust force')
            xlabel(app.UIAxes, 'Velocity of exhaust gas')
            ylabel(app.UIAxes, 'Thrust force')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Graphs_tab_1);
            title(app.UIAxes_2, 'Propelleant mass flow rate vs specific impulse')
            xlabel(app.UIAxes_2, 'mass flow rate')
            ylabel(app.UIAxes_2, 'specific impulse')
            zlabel(app.UIAxes_2, 'Z')
            app.Graphs_Tab_2 = uitab(app.Graphs_tab);
            app.Graphs_Tab_2.Title = 'State_points';
            app.UIAxes_temp_vs_entropy = uiaxes(app.Graphs_Tab_2);
            title(app.UIAxes_temp_vs_entropy, 'Temperature vs entropy')
            xlabel(app.UIAxes_temp_vs_entropy, 'Entropy (J/kg*K)')
            ylabel(app.UIAxes_temp_vs_entropy, 'Temperature(K)')
            zlabel(app.UIAxes_temp_vs_entropy, 'Z')
            app.UIAxes_pressure_vs_entropy = uiaxes(app.Graphs_Tab_2);
            title(app.UIAxes_pressure_vs_entropy, 'Pressure vs entropy')
            xlabel(app.UIAxes_pressure_vs_entropy, 'Entropy (J/kg*K)')
            ylabel(app.UIAxes_pressure_vs_entropy, 'Pressure(kpa)')
            zlabel(app.UIAxes_pressure_vs_entropy, 'Z')
            app.GraphsLabel = uilabel(app.UIFigure);
            app.GraphsLabel.Text = 'Graphs';
            app.Switch_pump_outlet_optimization = uiswitch(app.UIFigure, 'slider');
            app.Switch_pump_outlet_optimization.Value = 'On';
            app.Switch_Mass_flow_rate_optimization = uiswitch(app.UIFigure, 'slider');
            app.OptimiziationusingLabel = uilabel(app.UIFigure);
            app.OptimiziationusingLabel.Text = 'Optimiziation using';
            app.PumpoutletpressureLabel = uilabel(app.UIFigure);
            app.PumpoutletpressureLabel.Text = 'Pump outlet pressure';
            app.PumpoutletpressureLabel_2 = uilabel(app.UIFigure);
            app.PumpoutletpressureLabel_2.Text = 'U_A value of heat exchanger';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = '%Error';
            app.Percentage_error = uieditfield(app.UIFigure, 'numeric');
            app.Percentage_error.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Nuclear_thermal_rocket_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end